classdef hipertenzija_app_designer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PredvidjenirizikodhipertenzijeEditField  matlab.ui.control.NumericEditField
        PredvidjenirizikodhipertenzijeEditFieldLabel  matlab.ui.control.Label
        DijastolnikrvnipritisakEditField  matlab.ui.control.NumericEditField
        DijastolnikrvnipritisakEditFieldLabel  matlab.ui.control.Label
        SistolnikrvnipritisakEditField  matlab.ui.control.NumericEditField
        SistolnikrvnipritisakEditFieldLabel  matlab.ui.control.Label
        BMIEditField                    matlab.ui.control.NumericEditField
        BMIEditFieldLabel               matlab.ui.control.Label
        GodineEditField                 matlab.ui.control.NumericEditField
        GodineEditFieldLabel            matlab.ui.control.Label
        KRAJButton                      matlab.ui.control.Button
        IZRAUNATIButton                 matlab.ui.control.Button
        UnesitepodatkeopacijentuLabel   matlab.ui.control.Label
        RaunanjerizikaodhipertenzijepacijentaLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: KRAJButton
        function KRAJButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Button pushed function: IZRAUNATIButton
        function IZRAUNATIButtonPushed(app, event)
    % 1) Ucitavanje vrednosti iz polja
    age = app.GodineEditField.Value;
    bmi = app.BMIEditField.Value;
    sbp = app.SistolnikrvnipritisakEditField.Value;
    dbp = app.DijastolnikrvnipritisakEditField.Value;
    
    % 2) Definicija realnih opsega
    min_vals = [90   60   18   15];   % [SBP  DBP  Age  BMI]
    max_vals = [200 120  90   45];
    
    % 3) Provera da li su unete vrednosti u dozvoljenom opsegu
    if sbp < min_vals(1) || sbp > max_vals(1) || ...
       dbp < min_vals(2) || dbp > max_vals(2) || ...
       age < min_vals(3) || age > max_vals(3) || ...
       bmi < min_vals(4) || bmi > max_vals(4)
   
        uialert(app.UIFigure, ...
            ['Unete vrednosti su van dozvoljenog opsega.' newline ...
             'SBP: 90–200, DBP: 60–120, Godine: 18–90, BMI: 15–45.'], ...
            'Neispravan unos');
        return;
    end
    
    % 4) Vektora ulaza
 
    inputs = [sbp, dbp, age, bmi];
    
    % 5) Min-max skaliranje u [0, 1]
    inputs_scaled = (inputs - min_vals) ./ (max_vals - min_vals);
    
    % 6) Ucitavanje istreniranog ANFIS modela
    fis = readfis('AnfisModel_Hipertenzija.fis');
    
    % 7) Izracunavanje rizika
    risk = evalfis(fis, inputs_scaled);
    
    % Ogranicavanje na [0,1]
    risk = max(0, min(1, risk));
    
    % 8) Prikaz brojcane vrednosti izlaza
    app.PredvidjenirizikodhipertenzijeEditField.Value = risk;
    
    % 9) Graficki prikaz – bar plot
    nizak_rizik = 1 - risk;
    visok_rizik = risk;
    
    bar(app.UIAxes, [nizak_rizik, visok_rizik]);
    app.UIAxes.XTick = [1 2];
    app.UIAxes.XTickLabel = {'Nizak rizik', 'Visok rizik'};
    app.UIAxes.YLim = [0 1];
    ylabel(app.UIAxes, 'Verovatnoća');
    title(app.UIAxes, 'Predviđeni rizik od hipertenzije');
    grid(app.UIAxes, 'on');
    app.UIAxes.XLabel.String = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RaunanjerizikaodhipertenzijepacijentaLabel = uilabel(app.UIFigure);
            app.RaunanjerizikaodhipertenzijepacijentaLabel.Text = 'Računanje rizika od hipertenzije pacijenta';
            app.UnesitepodatkeopacijentuLabel = uilabel(app.UIFigure);
            app.UnesitepodatkeopacijentuLabel.Text = 'Unesite podatke o pacijentu:';
            app.IZRAUNATIButton = uibutton(app.UIFigure, 'push');
            app.IZRAUNATIButton.ButtonPushedFcn = createCallbackFcn(app, @IZRAUNATIButtonPushed, true);
            app.IZRAUNATIButton.Text = 'IZRAČUNATI';
            app.KRAJButton = uibutton(app.UIFigure, 'push');
            app.KRAJButton.ButtonPushedFcn = createCallbackFcn(app, @KRAJButtonPushed, true);
            app.KRAJButton.Text = 'KRAJ';
            app.GodineEditFieldLabel = uilabel(app.UIFigure);
            app.GodineEditFieldLabel.Text = 'Godine';
            app.GodineEditField = uieditfield(app.UIFigure, 'numeric');
            app.GodineEditField.Limits = [18 90];
            app.GodineEditField.Value = 18;
            app.BMIEditFieldLabel = uilabel(app.UIFigure);
            app.BMIEditFieldLabel.Text = 'BMI';
            app.BMIEditField = uieditfield(app.UIFigure, 'numeric');
            app.BMIEditField.Limits = [15 45];
            app.BMIEditField.Value = 15;
            app.SistolnikrvnipritisakEditFieldLabel = uilabel(app.UIFigure);
            app.SistolnikrvnipritisakEditFieldLabel.Text = 'Sistolni krvni pritisak';
            app.SistolnikrvnipritisakEditField = uieditfield(app.UIFigure, 'numeric');
            app.SistolnikrvnipritisakEditField.Limits = [90 200];
            app.SistolnikrvnipritisakEditField.Value = 90;
            app.DijastolnikrvnipritisakEditFieldLabel = uilabel(app.UIFigure);
            app.DijastolnikrvnipritisakEditFieldLabel.Text = 'Dijastolni krvni pritisak';
            app.DijastolnikrvnipritisakEditField = uieditfield(app.UIFigure, 'numeric');
            app.DijastolnikrvnipritisakEditField.Limits = [60 120];
            app.DijastolnikrvnipritisakEditField.Value = 60;
            app.PredvidjenirizikodhipertenzijeEditFieldLabel = uilabel(app.UIFigure);
            app.PredvidjenirizikodhipertenzijeEditFieldLabel.Text = 'Predvidjeni rizik od hipertenzije';
            app.PredvidjenirizikodhipertenzijeEditField = uieditfield(app.UIFigure, 'numeric');
            app.PredvidjenirizikodhipertenzijeEditField.Limits = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hipertenzija_app_designer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end